classdef Zadanie3App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        loha1Tab                       matlab.ui.container.Tab
        OtvoritprieinoksvsledkamiButton  matlab.ui.control.Button
        OtvoritsborsvsledkamiButton    matlab.ui.control.Button
        Results                        matlab.ui.control.ListBox
        PomerfariebEditField_2         matlab.ui.control.EditField
        PomerfariebEditField_2Label    matlab.ui.control.Label
        PomerfariebzadvajteakocelslaoddelenmedzerouLabel_2  matlab.ui.control.Label
        AktualizovaButton              matlab.ui.control.Button
        PomerfariebzadvajteakocelslaoddelenmedzerouLabel  matlab.ui.control.Label
        PomerfariebEditField           matlab.ui.control.EditField
        PomerfariebEditFieldLabel      matlab.ui.control.Label
        ZtohojeListBox                 matlab.ui.control.ListBox
        ZtohojeListBoxLabel            matlab.ui.control.Label
        VsledkyLabel                   matlab.ui.control.Label
        NastavihodnotyzozadaniaButton  matlab.ui.control.Button
        VypotapravdepodobnosButton     matlab.ui.control.Button
        numberOfPicked                 matlab.ui.control.Spinner
        VytiahnemeksLabel              matlab.ui.control.Label
        VytiahnutgulikybudButtonGroup  matlab.ui.container.ButtonGroup
        rzneButton                     matlab.ui.control.RadioButton
        rovnakButton                   matlab.ui.control.RadioButton
        numberOfColors                 matlab.ui.control.Spinner
        PoetfariebguliiekLabel         matlab.ui.control.Label
        numberOfBalls                  matlab.ui.control.Spinner
        PoetguliiekvurneLabel          matlab.ui.control.Label
        ZadanieLabel                   matlab.ui.control.Label
        loha2Tab                       matlab.ui.container.Tab
        results2                       matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        OtvoritprieinoksvsledkamiButton_2  matlab.ui.control.Button
        OtvoritsborsvsledkamiButton_2  matlab.ui.control.Button
        VsledkyLabel_2                 matlab.ui.control.Label
        NastavihodnotyzozadaniaButton_2  matlab.ui.control.Button
        VypotaminimlnypoethodovButton  matlab.ui.control.Button
        numberOfSite3                  matlab.ui.control.Spinner
        Poetpadnutstranyslo3Label      matlab.ui.control.Label
        numberOfRolls                  matlab.ui.control.Spinner
        PoethodovprvoukockouLabel      matlab.ui.control.Label
        second                         matlab.ui.control.DropDown
        KockaktorousahdedruhouLabel    matlab.ui.control.Label
        KockaktorousahdeprvouLabel     matlab.ui.control.Label
        ZadanieLabel_2                 matlab.ui.control.Label
        first                          matlab.ui.control.DropDown
        loha3Tab                       matlab.ui.container.Tab
        NastavihodnotyzozadaniaButton_3  matlab.ui.control.Button
        SpustianalzudtButton           matlab.ui.control.Button
        Spinner_2                      matlab.ui.control.Spinner
        DolnhranicaprvejtriedyLabel    matlab.ui.control.Label
        PoettriedpoetnostiLabel        matlab.ui.control.Label
        Spinner                        matlab.ui.control.Spinner
        VybraButton                    matlab.ui.control.Button
        VstupnsborLabel                matlab.ui.control.Label
        fileName                       matlab.ui.control.EditField
        ZadanieLabel_3                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        BallsPicked
        NumberOfPickedBalls
        NumberOfAllBalls
        NumberOfColors
        BallsInPack
        BallsPickedColors
        pravdepodobnostRovnake
        pravdepodobnostRozne

        firstValue
        secondValue
        allRolls
        successRolls
        pravdepodobnostPrva
        pocetHodov

        selectedFile
        selectedPath
    end
        
    methods (Access = private)
        
        % nastavenie radio button menu na vyber toho ci sa vyberaju orvnake
        % alebo rozne gulicky na rozne
        function setPickedToRozne(app)
            app.rzneButton.Value = true;
            app.rovnakButton.Value = false;
        end

        % nastavenie radio button menu na vyber toho ci sa vyberaju orvnake
        % alebo rozne gulicky na rovnake
        function setPickedToRovnake(app)
            app.rzneButton.Value = false;
            app.rovnakButton.Value = true;
        end

        % ulozenie vypocitanych hodnot aj s komentarom do suboru
        function storeResultsForSamePick(app)
            pickedBallsCount = app.NumberOfPickedBalls;
            allColorsCount = app.NumberOfColors;

            if ~exist('OutputFiles', 'dir')
               mkdir OutputFiles
            end

            NameOfFile = ('Pravdepodobnost1.txt'); % nazov suboru
            Fpath1 = fullfile('OutputFiles',NameOfFile); % cesta k nemu
            fileID1 = fopen(Fpath1,'w','n','UTF-8'); % otvorneie na pridavanie

            fprintf(fileID1,'Vstupne hodnoty: \r\n');
            fprintf(fileID1,'----------------- \r\n');
            fprintf(fileID1,'Pocet vsetkych guliciek: %d\r\n', app.NumberOfAllBalls);
            fprintf(fileID1,'Pocet roznych farieb guliciek: %d\r\n', app.NumberOfColors);

            fprintf(fileID1,'Pocet guliciek konkretnych farieb\r\n');
            for i=1:app.NumberOfColors
                text = ['Farba ', num2str(i), ': ', num2str(app.BallsInPack(i)), 'ks'];
                fprintf(fileID1,'\t%s\r\n', text);
            end

            fprintf(fileID1,'Pocet vybratych guliciek: %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1,'Vytiahnute gulicky su: rovnake\r\n\r\n');
            
            fprintf(fileID1,'VZOREC: \r\n');
            fprintf(fileID1,'-------- \r\n');
            fprintf(fileID1, 'r - kolko je sposobov na vytiehnutie %d guliek z urny (ked nam nezalezi na farbe tahanych)\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'r = nchoosek(allBallsCount, pickedBallsCount)\r\n\r\n');
            fprintf(fileID1, 'm - kolko je sposobov aby sme spomedzi vsetkych guliek jednej farby vybrali prave %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'm = nchoosek(numberOfThisColor, pickedBallsCount)\r\n\r\n');

            fprintf(fileID1, 'P = m / r\r\n\r\n');

            fprintf(fileID1,'VYSLEDOK: \r\n');
            fprintf(fileID1,'---------- \r\n');
            fprintf(fileID1,'Pravdepodobnost vybratia %s guliciek farby \r\n', num2str(pickedBallsCount));
            for i=1:allColorsCount
                if app.pravdepodobnostRovnake(i) == -1
                    text = ['F', num2str(i), ' sa neda vypocitat nakolko chceme vytiahnut viac guliciek ako je z tej farby v urne.'];
                else
                    text = ['F', num2str(i), ' je: ', num2str(app.pravdepodobnostRovnake(i))];
                end
                fprintf(fileID1,'\t%s\r\n', text);
            end

            fclose(fileID1);
        end
        
        function storeResultsForDifferentPick(app)
            pickedBallsCount = app.NumberOfPickedBalls;
            allColorsCount = app.NumberOfColors;

            if ~exist('OutputFiles', 'dir')
               mkdir OutputFiles
            end

            NameOfFile = ('Pravdepodobnost1.txt'); % nazov suboru
            Fpath1 = fullfile('OutputFiles',NameOfFile); % cesta k nemu
            fileID1 = fopen(Fpath1,'w','n','UTF-8'); % otvorneie na pridavanie

            fprintf(fileID1,'Vstupne hodnoty: \r\n');
            fprintf(fileID1,'----------------- \r\n');
            fprintf(fileID1,'Pocet vsetkych guliciek: %d\r\n', app.NumberOfAllBalls);
            fprintf(fileID1,'Pocet roznych farieb guliciek: %d\r\n', app.NumberOfColors);

            fprintf(fileID1,'Pocet guliciek konkretnych farieb\r\n');
            for i=1:app.NumberOfColors
                text = ['Farba ', num2str(i), ': ', num2str(app.BallsInPack(i)), 'ks'];
                fprintf(fileID1,'\t%s\r\n', text);
            end

            fprintf(fileID1,'Pocet vybratych guliciek: %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1,'Vytiahnute gulicky su: rozne\r\n');
            fprintf(fileID1,'Pomer farieb vytiahnutych guliciek\r\n\r\n');
            for i=1:app.NumberOfColors
                text = ['Farba ', num2str(i), ': ', num2str(app.BallsPickedColors(i)), 'ks'];
                fprintf(fileID1,'\t%s\r\n', text);
            end
            
            fprintf(fileID1,'VZOREC: \r\n');
            fprintf(fileID1,'-------- \r\n');
            fprintf(fileID1, 'r - kolko je sposobov na vytiehnutie %d guliek z urny (ked nam nezalezi na farbe tahanych)\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'r = nchoosek(allBallsCount, pickedBallsCount)\r\n\r\n');
            fprintf(fileID1, 'm - kolkymi sposobmi sa da dosiahnut pozadovany pomer vytiahnutych farieb %d\r\n', app.NumberOfPickedBalls);
            fprintf(fileID1, 'm = nchoosek(n1, k1) * nchoosek(n2, k2) * ... * nchoosek(nx, kx)\r\n');
            fprintf(fileID1, '    pre x = <1; pocet vsetkych farieb>, kde nx je pocet guliciek danej farby v urne a kx pocet guliciek danej farby ktore sa vyberu\r\n\r\n');

            fprintf(fileID1, 'P = m / r\r\n\r\n');

            fprintf(fileID1,'VYSLEDOK: \r\n');
            fprintf(fileID1,'---------- \r\n');
            fprintf(fileID1,'Pravdepodobnost vybratia %s guliciek vo vyssie uvedenom pomere je %s \r\n', num2str(pickedBallsCount), num2str(app.pravdepodobnostRozne));
            fclose(fileID1);
        end

        % vypocet pravdepodobnosti v pripade ze je zvolena moznost rovnake
        % gulicky
        function pickedSameBalls(app)
            allBallsCount = app.NumberOfAllBalls;
            pickedBallsCount = app.NumberOfPickedBalls;
            allColorsCount = app.NumberOfColors;
            app.pravdepodobnostRovnake = [];
            r = nchoosek(allBallsCount, pickedBallsCount); % kolkymi moznostami vieme vybrat urceny pocet guliciek ked neriesime farbu
            
            for i=1:allColorsCount
                numberOfThisColor = app.BallsInPack(i);
                if pickedBallsCount > numberOfThisColor
                    P = -1;
                else
                    m = nchoosek(numberOfThisColor, pickedBallsCount);
                    P = m / r;
                end
                app.pravdepodobnostRovnake(end+1) = P;
            end

            app.Results.Items = {};
            drawnow

            for i=1:allColorsCount
                if app.pravdepodobnostRovnake(i) == -1
                    name = ['Pravdepodobnost vybratia ', num2str(pickedBallsCount), ' guliciek farby ', num2str(i), ' sa nedá vypočítať nakoľko chceme vytiahnuť viac guličiek ako je z tej farby v urne'];
                else
                    name = ['Pravdepodobnost vybratia ', num2str(pickedBallsCount), ' guliciek farby ', num2str(i), ' je: ', num2str(app.pravdepodobnostRovnake(i))];
                end
                app.Results.Items(end+1) = {name};
                drawnow
            end
            storeResultsForSamePick(app);
        end
        
        % vypocet pravdepodobnosti v pripade ze je zvolena moznost rozne
        % gulicky
        function pickedDifferentBalls(app)
            % rozparsovanie zadaneho pomeru farieb ktore sa vyberu
            values = app.PomerfariebEditField_2.Value;
            splitedValues = strsplit(values);

            [~,numberOfValues] = size(splitedValues);
            
            app.BallsPickedColors = [];
            
            newBallCount = 0;
            inputBallCount = app.NumberOfPickedBalls;

            colorsCount = app.numberOfColors.Value;

            for i = 1:min(numberOfValues, colorsCount)
                currentValue = str2double(splitedValues(i));
                if isnan(currentValue)
                    uialert(app.UIFigure,'Medzi zadanými hodnotami sa nachádzajú aj iné znaky ako čísla, prípadne medzera na konci navyše','Chyba');
                    return
                end
                app.BallsPickedColors(end+1) = currentValue;
                newBallCount = newBallCount + currentValue;
            end

            if newBallCount < inputBallCount && numberOfValues >= colorsCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je menší ako počet vybraných guličiek.','Chyba');
                return
            end 

            if newBallCount > inputBallCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je väčší ako počet vybraných guličiek.','Chyba');
                return
            end

            if numberOfValues > colorsCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je vyšší ako počet farieb. Hodnoty navyše budú zahodené','Varovanie', 'Icon', 'warning');
            end

            countOneColor = 0;
            m = 0;

            if numberOfValues < colorsCount && newBallCount ~= inputBallCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je menší ako počet farieb. Zvyšný diel bude rovnomerne rozdelený medzi zvyšné farby','Varovanie', 'Icon', 'warning');
                
                missingBalls = inputBallCount - newBallCount;
                missingColors = colorsCount - numberOfValues;
                countOneColor = floor(missingBalls / missingColors);
                m = mod(missingBalls, missingColors);
            end
            
            middleStop = min(numberOfValues, colorsCount) + 1;
            for i=middleStop:colorsCount
                if i == middleStop
                    app.BallsPickedColors(end+1) = m + countOneColor;
                else
                    app.BallsPickedColors(end+1) = countOneColor;
                end

            end

            % vypocet pravdepodobnosti
            allBallsCount = app.NumberOfAllBalls;
            pickedBallsCount = app.NumberOfPickedBalls;

            allColorsCount = app.NumberOfColors;

            r = nchoosek(allBallsCount, pickedBallsCount); % kolkymi moznostami vieme vybrat urceny pocet guliciek ked neriesime farbu
            
            % vypocet kolkymi sposobmi vie vybrat pozadovany pomer farieb
            m = 1;
            disp(app.BallsPickedColors);
            for i=1:allColorsCount
                numberOfThisColor = app.BallsInPack(i);
                numberOfPickedOfThisColor = app.BallsPickedColors(i);

                if numberOfPickedOfThisColor > numberOfThisColor
                    uialert(app.UIFigure, ['Počet guličiek ktoré chceme vybrať z danej farby je vyšší ako celkový počet guličiek. (Farba číslo ', num2str(i), ')'], 'Chyba pri farbe ');
                    return
                end
                m = m * nchoosek(numberOfThisColor, numberOfPickedOfThisColor);
            end

            P = m / r;
            app.pravdepodobnostRozne = P;

            app.Results.Items = {};
            drawnow

            name = ['Pravdepodobnost vybratia ', num2str(pickedBallsCount), ' guliciek v pomere:'];
            app.Results.Items(end+1) = {name};
            for i=1:allColorsCount
                name = ['    Farba ', num2str(i), ': ', num2str(app.BallsPickedColors(i)), ' ks'];
                app.Results.Items(end+1) = {name};
                drawnow
            end

            name = ['je: ', num2str(P)];
            app.Results.Items(end+1) = {name};

            storeResultsForDifferentPick(app);
        end
        
        % na zistenie kolko je ktorych farieb v urne
        function getBallsInPack(app)
            app.BallsInPack = [];
            listOfBalls = app.ZtohojeListBox.Items;
            for i=1:app.NumberOfColors 
                oneLine = split(listOfBalls(i));
                app.BallsInPack(end+1) = str2double(oneLine(3));
            end
        end
      
        % updatuje listbox s tym kolko je ktorych farieb v urne
        function updateListboxWithColors(app)
            app.ZtohojeListBox.Items = {};
            drawnow
            
            countBalls = app.numberOfBalls.Value;
            countColors = app.numberOfColors.Value;
            countOneColor = floor(countBalls / countColors);
            m = mod(countBalls, countColors);
            for i=1:countColors
                countString = num2str(countOneColor);
                if i == 1
                    countString = num2str(countOneColor + m);
                end
                name = ['farba ', num2str(i), ': ', countString, ' ks'];
                app.ZtohojeListBox.Items(end+1) = {name};
                drawnow
            end
        end
    
        % vypocet pravdepodobnosti pre hod prvym telesom
        function probabilityFirst(app)
            p = 1 / app.firstValue;
            n = app.allRolls;
            k = app.successRolls;
            disp(['k: ', num2str(k), ' n: ', num2str(n)]);
            P = nchoosek(n, k) * (p ^ k) * ((1-p)^(n-k));
            app.pravdepodobnostPrva = P;
        end
        
        % zistovanie kolkokrat treba hodit druhym
        function countRolls(app)
            p = 1 / app.secondValue;

            k = app.successRolls;
            n = k;
            P = nchoosek(n, k) * (p ^ k) * ((1-p)^(n-k));
            
            while P < app.pravdepodobnostPrva
                n = n + 1;
                k = app.successRolls;
                P = nchoosek(n, k) * (p ^ k) * ((1-p)^(n-k));
            end

            app.pocetHodov = n;
        end
    
        function storeResults2(app)
            if ~exist('OutputFiles', 'dir')
               mkdir OutputFiles
            end

            NameOfFile = ('Pravdepodobnost2.txt'); % nazov suboru
            Fpath1 = fullfile('OutputFiles',NameOfFile); % cesta k nemu
            fileID2 = fopen(Fpath1,'w','n','UTF-8'); % otvorneie na pridavanie

            fprintf(fileID2,'Vstupne hodnoty: \r\n');
            fprintf(fileID2,'----------------- \r\n');
            fprintf(fileID2, 'Prve teleso ktorym sa hadze je %d-sten\r\n', app.firstValue);
            fprintf(fileID2, 'Druhe teleso ktorym sa hadze je %d-sten\r\n', app.secondValue);

            fprintf(fileID2, 'Prvym telesom sme hodili %d-krat\r\n', app.allRolls);
            fprintf(fileID2, 'Strana s cislo 3 spadla %d-krat\r\n\r\n', app.successRolls);

            fprintf(fileID2,'VZOREC: \r\n');
            fprintf(fileID2,'-------- \r\n');
            
            fprintf(fileID2,'VYSLEDOK: \r\n');
            fprintf(fileID2,'---------- \r\n');
            fprintf(fileID2, 'Druhym telesom musime hodit aspon %d-krat aby pravdepodobnost toho ze cislo 3 spadne %d-krat bola vacsia ako v pokuse s prvym telesom.\r\n', app.pocetHodov, app.successRolls);

            fclose(fileID2);
        end
    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: numberOfColors
        function numberOfColorsValueChanged(app, event)
            updateListboxWithColors(app);
        end

        % Button pushed function: AktualizovaButton
        function AktualizovaButtonPushed(app, event)
            values = app.PomerfariebEditField.Value;
            splitedValues = strsplit(values);

            [~,numberOfValues] = size(splitedValues);
            
            splitedValuesInt = [];
            
            newBallCount = 0;
            inputBallCount = app.numberOfBalls.Value;

            colorsCount = app.numberOfColors.Value;

            for i = 1:min(numberOfValues, colorsCount)
                currentValue = str2double(splitedValues(i));
                if isnan(currentValue)
                    uialert(app.UIFigure,'Medzi zadanými hodnotami sa nachádzajú aj iné znaky ako čísla, prípadne medzera na konci navyše','Chyba');
                    return
                end
                splitedValuesInt(end+1) = currentValue;
                newBallCount = newBallCount + currentValue;
            end

            if newBallCount < inputBallCount && numberOfValues >= colorsCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je menší ako počet guličiek.','Chyba');
                return
            end 

            if newBallCount > inputBallCount
                uialert(app.UIFigure,'Súčet zadaných hodnôt je väčší ako počet guličiek.','Chyba');
                return
            end

            if numberOfValues > colorsCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je vyšší ako počet farieb. Hodnoty navyše budú zahodené','Varovanie', 'Icon', 'warning');
            end

            countOneColor = 0;
            m = 0;

            if numberOfValues < colorsCount
                uialert(app.UIFigure,'Počet zadaných hodnôt je menší ako počet farieb. Zvyšný diel bude rovnomerne rozdelený medzi zvyšné farby','Varovanie', 'Icon', 'warning');
                
                missingBalls = inputBallCount - newBallCount;
                missingColors = colorsCount - numberOfValues;
                countOneColor = floor(missingBalls / missingColors);
                m = mod(missingBalls, missingColors);
            end

            app.ZtohojeListBox.Items = {};
            drawnow
            
            middleStop = min(numberOfValues, colorsCount) + 1;
            for i=middleStop:colorsCount
                if i == middleStop
                    splitedValuesInt(end+1) = m + countOneColor;
                else
                    splitedValuesInt(end+1) = countOneColor;
                end

            end

            for i=1:colorsCount
                name = ['farba ', num2str(i), ': ', num2str(splitedValuesInt(i)), ' ks'];
                app.ZtohojeListBox.Items(end+1) = {name};
                drawnow
            end
        end

        % Button pushed function: NastavihodnotyzozadaniaButton
        function NastavihodnotyzozadaniaButtonPushed(app, event)
            app.numberOfBalls.Value = 150;
            app.numberOfColors.Value = 2;
            app.ZtohojeListBox.Items = {};
            drawnow
            app.ZtohojeListBox.Items(end+1) = {'farba 1: 50 ks'};
            app.ZtohojeListBox.Items(end+1) = {'farba 2: 100 ks'};
            drawnow
            setPickedToRozne(app);
            app.numberOfPicked.Value = 2;
            app.PomerfariebEditField_2.Value = '1 1';
        end

        % Button pushed function: VypotapravdepodobnosButton
        function VypotapravdepodobnosButtonPushed(app, event)
            app.NumberOfAllBalls = app.numberOfBalls.Value;
            app.NumberOfColors = app.numberOfColors.Value;
            app.NumberOfPickedBalls = app.numberOfPicked.Value;
            getBallsInPack(app);

            if app.rzneButton.Value == false && app.rovnakButton.Value == true
                pickedRozne = false;
            elseif app.rzneButton.Value == true && app.rovnakButton.Value == false
                pickedRozne = true;
            else
                uialert(app.UIFigure,'Chyba pri zadávaní toho či sa vyberú rovnaké alebo rôzne guličky.','Chyba');
                return
            end

            if pickedRozne == true
                pickedDifferentBalls(app);
            else
                pickedSameBalls(app);
            end
        end

        % Button pushed function: OtvoritsborsvsledkamiButton
        function OtvoritsborsvsledkamiButtonPushed(app, event)
            !notepad OutputFiles\Pravdepodobnost1.txt
        end

        % Value changed function: numberOfBalls
        function numberOfBallsValueChanged(app, event)
            updateListboxWithColors(app);
            m = app.numberOfBalls.Value;
            if app.numberOfColors.Value >= m
                app.numberOfColors.Value = m;
            end
            if app.numberOfPicked.Value >= m
                app.numberOfPicked.Value = m;
            end
            app.numberOfColors.Limits = [1, m];
            app.numberOfPicked.Limits = [1, m];
        end

        % Button pushed function: OtvoritprieinoksvsledkamiButton, 
        % OtvoritprieinoksvsledkamiButton_2
        function OtvoritprieinoksvsledkamiButtonPushed(app, event)
            !start OutputFiles\
        end

        % Button pushed function: NastavihodnotyzozadaniaButton_2
        function NastavihodnotyzozadaniaButton_2Pushed(app, event)
             app.first.Value = '6-sten';
             app.second.Value = '12-sten';
             app.numberOfRolls.Value = 10;
             app.numberOfSite3.Value = 4;
        end

        % Button pushed function: OtvoritsborsvsledkamiButton_2
        function OtvoritsborsvsledkamiButton_2Pushed(app, event)
            !notepad OutputFiles\Pravdepodobnost2.txt
        end

        % Value changed function: numberOfRolls
        function numberOfRollsValueChanged(app, event)
            value = app.numberOfRolls.Value;
            if app.numberOfSite3 > value
                app.numberOfSite3.Value = value;
            end
            app.numberOfSite3.Limits = [1, value];
        end

        % Value changed function: first
        function firstValueChanged(app, event)
            value = app.first.Value;
            splited = strsplit(value, '-');
            n = str2double(splited(1));
            secondValue = app.second.Value;
            splited = strsplit(secondValue, '-');
            sV = str2double(splited(1));

            listOfobjects = app.second.Items;

            app.second.Items = {};
            drawnow

            [~,numberOfValues] = size(listOfobjects);

            for i=1:numberOfValues
                splited = split(listOfobjects(i), '-');
                x = str2double(splited(1));
                if x >= n
                    app.second.Items(end+1) = listOfobjects(i);
                    drawnow
                end
            end

            if sV >= n
                app.second.Value = secondValue;
            end
        end

        % Value changed function: second
        function secondValueChanged(app, event)
            value = app.second.Value;
            splited = strsplit(value, '-');
            n = str2double(splited(1));
            firstValue = app.first.Value;
            splited = strsplit(firstValue, '-');
            fV = str2double(splited(1));

            listOfobjects = app.first.Items;

            app.first.Items = {};
            drawnow

            [~,numberOfValues] = size(listOfobjects);

            for i=1:numberOfValues
                splited = split(listOfobjects(i), '-');
                x = str2double(splited(1));
                if x <= n
                    app.first.Items(end+1) = listOfobjects(i);
                    drawnow
                end
            end

            if fV <= n
                app.first.Value = firstValue;
            end
        end

        % Button pushed function: VypotaminimlnypoethodovButton
        function VypotaminimlnypoethodovButtonPushed(app, event)
            value = app.first.Value;
            splited = strsplit(value, '-');
            app.firstValue = str2double(splited(1));

            value = app.second.Value;
            splited = strsplit(value, '-');
            app.secondValue = str2double(splited(1));

            app.allRolls = app.numberOfRolls.Value;
            app.successRolls = app.numberOfSite3.Value;

            probabilityFirst(app);

            countRolls(app);

            app.results2.Value = ['Druhou kockou musíme hodiť minimálne ', num2str(app.pocetHodov), '-krát aby pravdepodobnosť padnutia čísla 3 bola vyššia ako v prípade hádzania prvou kockou'];

            storeResults2(app);
        end

        % Button down function: loha3Tab
        function loha3TabButtonDown(app, event)
             app.selectedFile = ('PopisnaStatistika-10.xlsx');
             app.selectedPath = ('InputFiles');
        end

        % Button pushed function: VybraButton
        function VybraButtonPushed(app, event)
            [file,path] =  uigetfile('*.xlsx', 'Vyber vstupný súbor');
            if isequal(file,0) 
               
            else
                app.selectedPath = path;
                app.selectedFile = file;
                disp(app.selectedPath);
                disp(app.selectedFile);
            end

            app.fileName.Value = app.selectedFile;
        end

        % Button pushed function: NastavihodnotyzozadaniaButton_3
        function NastavihodnotyzozadaniaButton_3Pushed(app, event)
            app.selectedFile = ('PopisnaStatistika-10.xlsx');
            app.selectedPath = ('InputFiles');
            app.fileName.Value = app.selectedFile;
                disp(app.selectedPath);
                disp(app.selectedFile);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.loha1Tab = uitab(app.TabGroup);
            app.loha1Tab.Title = 'Úloha 1';
            app.ZadanieLabel = uilabel(app.loha1Tab);
            app.ZadanieLabel.Text = 'Zadanie';
            app.PoetguliiekvurneLabel = uilabel(app.loha1Tab);
            app.PoetguliiekvurneLabel.Text = 'Počet guličiek v urne';
            app.numberOfBalls = uispinner(app.loha1Tab);
            app.numberOfBalls.Limits = [1 Inf];
            app.numberOfBalls.ValueChangedFcn = createCallbackFcn(app, @numberOfBallsValueChanged, true);
            app.numberOfBalls.Value = 100;
            app.PoetfariebguliiekLabel = uilabel(app.loha1Tab);
            app.PoetfariebguliiekLabel.Text = 'Počet farieb guličiek';
            app.numberOfColors = uispinner(app.loha1Tab);
            app.numberOfColors.Limits = [1 100];
            app.numberOfColors.ValueChangedFcn = createCallbackFcn(app, @numberOfColorsValueChanged, true);
            app.numberOfColors.Value = 1;
            app.VytiahnutgulikybudButtonGroup = uibuttongroup(app.loha1Tab);
            app.VytiahnutgulikybudButtonGroup.Title = 'Vytiahnuté guličky budú';
            app.rovnakButton = uiradiobutton(app.VytiahnutgulikybudButtonGroup);
            app.rovnakButton.Text = 'rovnaké';
            app.rovnakButton.Value = true;
            app.rzneButton = uiradiobutton(app.VytiahnutgulikybudButtonGroup);
            app.rzneButton.Text = 'rôzne';
            app.VytiahnemeksLabel = uilabel(app.loha1Tab);
            app.VytiahnemeksLabel.Text = 'Vytiahneme                          ks.';
            app.numberOfPicked = uispinner(app.loha1Tab);
            app.numberOfPicked.Limits = [1 100];
            app.numberOfPicked.Value = 1;
            app.VypotapravdepodobnosButton = uibutton(app.loha1Tab, 'push');
            app.VypotapravdepodobnosButton.ButtonPushedFcn = createCallbackFcn(app, @VypotapravdepodobnosButtonPushed, true);
            app.VypotapravdepodobnosButton.Text = 'Vypočítať pravdepodobnosť';
            app.NastavihodnotyzozadaniaButton = uibutton(app.loha1Tab, 'push');
            app.NastavihodnotyzozadaniaButton.ButtonPushedFcn = createCallbackFcn(app, @NastavihodnotyzozadaniaButtonPushed, true);
            app.NastavihodnotyzozadaniaButton.Text = 'Nastaviť hodnoty zo zadania';
            app.VsledkyLabel = uilabel(app.loha1Tab);
            app.VsledkyLabel.Text = 'Výsledky';
            app.ZtohojeListBoxLabel = uilabel(app.loha1Tab);
            app.ZtohojeListBoxLabel.Text = 'Z toho je';
            app.ZtohojeListBox = uilistbox(app.loha1Tab);
            app.ZtohojeListBox.Items = {'farba 1: 100 ks'};
            app.ZtohojeListBox.Value = 'farba 1: 100 ks';
            app.PomerfariebEditFieldLabel = uilabel(app.loha1Tab);
            app.PomerfariebEditFieldLabel.Text = 'Pomer farieb';
            app.PomerfariebEditField = uieditfield(app.loha1Tab, 'text');
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel = uilabel(app.loha1Tab);
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel.Text = 'Pomer farieb zadávajte ako celé čísla oddelené medzerou. Poradie čísla na vstupe spadá pod farbu s daným číslom. Ak súčet hodnôt na vstupe bude menší ako celkový počet guličiek, chýbajúca časť sa rovnomerne rozdelí medzi guličky ktorým nebolo priradený pomer.';
            app.AktualizovaButton = uibutton(app.loha1Tab, 'push');
            app.AktualizovaButton.ButtonPushedFcn = createCallbackFcn(app, @AktualizovaButtonPushed, true);
            app.AktualizovaButton.Text = 'Aktualizovať';
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel_2 = uilabel(app.loha1Tab);
            app.PomerfariebzadvajteakocelslaoddelenmedzerouLabel_2.Text = 'V prípade, že bola zvolená možnosť ,,rôzne" napíšte, koľko z ktorej farby sa vytiahne. Princíp zapisovania je orvnaký ako pri nastavovaní pomeru farieb v urne.';
            app.PomerfariebEditField_2Label = uilabel(app.loha1Tab);
            app.PomerfariebEditField_2Label.Text = 'Pomer farieb';
            app.PomerfariebEditField_2 = uieditfield(app.loha1Tab, 'text');
            app.PomerfariebEditField_2.Value = '1';
            app.Results = uilistbox(app.loha1Tab);
            app.Results.Items = {};
            app.Results.Value = {};
            app.OtvoritsborsvsledkamiButton = uibutton(app.loha1Tab, 'push');
            app.OtvoritsborsvsledkamiButton.ButtonPushedFcn = createCallbackFcn(app, @OtvoritsborsvsledkamiButtonPushed, true);
            app.OtvoritsborsvsledkamiButton.Text = 'Otvorit súbor s výsledkami';
            app.OtvoritprieinoksvsledkamiButton = uibutton(app.loha1Tab, 'push');
            app.OtvoritprieinoksvsledkamiButton.ButtonPushedFcn = createCallbackFcn(app, @OtvoritprieinoksvsledkamiButtonPushed, true);
            app.OtvoritprieinoksvsledkamiButton.Text = 'Otvorit priečinok s výsledkami';
            app.loha2Tab = uitab(app.TabGroup);
            app.loha2Tab.Title = 'Úloha 2';
            app.first = uidropdown(app.loha2Tab);
            app.first.Items = {'4-sten', '6-sten', '8-sten', '12-sten'};
            app.first.ValueChangedFcn = createCallbackFcn(app, @firstValueChanged, true);
            app.first.Value = '4-sten';
            app.ZadanieLabel_2 = uilabel(app.loha2Tab);
            app.ZadanieLabel_2.Text = 'Zadanie';
            app.KockaktorousahdeprvouLabel = uilabel(app.loha2Tab);
            app.KockaktorousahdeprvouLabel.Text = 'Kocka ktorou sa hádže prvou';
            app.KockaktorousahdedruhouLabel = uilabel(app.loha2Tab);
            app.KockaktorousahdedruhouLabel.Text = 'Kocka ktorou sa hádže druhou';
            app.second = uidropdown(app.loha2Tab);
            app.second.Items = {'6-sten', '8-sten', '12-sten', '20-sten'};
            app.second.ValueChangedFcn = createCallbackFcn(app, @secondValueChanged, true);
            app.second.Value = '6-sten';
            app.PoethodovprvoukockouLabel = uilabel(app.loha2Tab);
            app.PoethodovprvoukockouLabel.Text = 'Počet hodov prvou kockou';
            app.numberOfRolls = uispinner(app.loha2Tab);
            app.numberOfRolls.Limits = [1 Inf];
            app.numberOfRolls.ValueChangedFcn = createCallbackFcn(app, @numberOfRollsValueChanged, true);
            app.numberOfRolls.Value = 10;
            app.Poetpadnutstranyslo3Label = uilabel(app.loha2Tab);
            app.Poetpadnutstranyslo3Label.Text = 'Počet padnutí strany číslo 3';
            app.numberOfSite3 = uispinner(app.loha2Tab);
            app.numberOfSite3.Limits = [1 10];
            app.numberOfSite3.Value = 1;
            app.VypotaminimlnypoethodovButton = uibutton(app.loha2Tab, 'push');
            app.VypotaminimlnypoethodovButton.ButtonPushedFcn = createCallbackFcn(app, @VypotaminimlnypoethodovButtonPushed, true);
            app.VypotaminimlnypoethodovButton.Text = 'Vypočítať minimálny počet hodov';
            app.NastavihodnotyzozadaniaButton_2 = uibutton(app.loha2Tab, 'push');
            app.NastavihodnotyzozadaniaButton_2.ButtonPushedFcn = createCallbackFcn(app, @NastavihodnotyzozadaniaButton_2Pushed, true);
            app.NastavihodnotyzozadaniaButton_2.Text = 'Nastaviť hodnoty zo zadania';
            app.VsledkyLabel_2 = uilabel(app.loha2Tab);
            app.VsledkyLabel_2.Text = 'Výsledky';
            app.OtvoritsborsvsledkamiButton_2 = uibutton(app.loha2Tab, 'push');
            app.OtvoritsborsvsledkamiButton_2.ButtonPushedFcn = createCallbackFcn(app, @OtvoritsborsvsledkamiButton_2Pushed, true);
            app.OtvoritsborsvsledkamiButton_2.Text = 'Otvorit súbor s výsledkami';
            app.OtvoritprieinoksvsledkamiButton_2 = uibutton(app.loha2Tab, 'push');
            app.OtvoritprieinoksvsledkamiButton_2.ButtonPushedFcn = createCallbackFcn(app, @OtvoritprieinoksvsledkamiButtonPushed, true);
            app.OtvoritprieinoksvsledkamiButton_2.Text = 'Otvorit priečinok s výsledkami';
            app.Label = uilabel(app.loha2Tab);
            app.Label.Text = '';
            app.results2 = uitextarea(app.loha2Tab);
            app.loha3Tab = uitab(app.TabGroup);
            app.loha3Tab.Title = 'Úloha 3';
            app.loha3Tab.ButtonDownFcn = createCallbackFcn(app, @loha3TabButtonDown, true);
            app.ZadanieLabel_3 = uilabel(app.loha3Tab);
            app.ZadanieLabel_3.Text = 'Zadanie';
            app.fileName = uieditfield(app.loha3Tab, 'text');
            app.fileName.Value = 'PopisnaStatistika-10.xlsx';
            app.VstupnsborLabel = uilabel(app.loha3Tab);
            app.VstupnsborLabel.Text = 'Vstupný súbor';
            app.VybraButton = uibutton(app.loha3Tab, 'push');
            app.VybraButton.ButtonPushedFcn = createCallbackFcn(app, @VybraButtonPushed, true);
            app.VybraButton.Text = 'Vybrať';
            app.Spinner = uispinner(app.loha3Tab);
            app.PoettriedpoetnostiLabel = uilabel(app.loha3Tab);
            app.PoettriedpoetnostiLabel.Text = 'Počet tried početnosti';
            app.DolnhranicaprvejtriedyLabel = uilabel(app.loha3Tab);
            app.DolnhranicaprvejtriedyLabel.Text = 'Dolná hranica prvej triedy';
            app.Spinner_2 = uispinner(app.loha3Tab);
            app.SpustianalzudtButton = uibutton(app.loha3Tab, 'push');
            app.SpustianalzudtButton.Text = 'Spustiť analýzu dát';
            app.NastavihodnotyzozadaniaButton_3 = uibutton(app.loha3Tab, 'push');
            app.NastavihodnotyzozadaniaButton_3.ButtonPushedFcn = createCallbackFcn(app, @NastavihodnotyzozadaniaButton_3Pushed, true);
            app.NastavihodnotyzozadaniaButton_3.Text = 'Nastaviť hodnoty zo zadania';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Zadanie3App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end